classdef MazeSolverTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        mazeOutput             matlab.ui.control.UIAxes           % Maze
        LabelNumericEditField  matlab.ui.control.Label            % Rows
        rowsInput              matlab.ui.control.NumericEditField % [1 30]
        LabelNumericEditField2 matlab.ui.control.Label            % Columns
        columnsInput           matlab.ui.control.NumericEditField % [1 30]
        solveMaze              matlab.ui.control.Button           % Solve
        stateCountDisplay      matlab.ui.control.UIAxes           % Average...
        Label                  matlab.ui.control.Label            % Number ...
        Label2                 matlab.ui.control.Label            % Total n...
        numberOfPunishments    matlab.ui.control.NumericEditField % [-Inf Inf]
        numberOfStatesTaken    matlab.ui.control.NumericEditField % [-Inf Inf]
        generateMaze           matlab.ui.control.Button           % Generat...
    end


    properties (Access = private)
        solveCounter % Description
        numberOfStatesTakenArray % Description
    end

    properties (Access = public)
        MazeX % Description
        MazeY % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Remove the numbers around the axis
             set(app.mazeOutput, 'XtickLabel',[],'YtickLabel',[])
            % Remove the tick marks in the axis
             set(app.mazeOutput, 'Xtick',[],'Ytick',[])
            %Flip the axis
            set(app.mazeOutput,'YDir','reverse')

%             app.numberOfTimesWeSolvedTheMaze = 0;
%             app.numberOfStates = 0;
%             app.numberOfTimesThroughTheMaze = 0;
            app.solveCounter = 0;
            app.MazeX = 0;
            app.MazeY = 0;
            app.numberOfStatesTakenArray = [];
            
%             
%             generateMazeButtonPushed(app)
%             solveMazeButtonPushed(app)
        end

        % generateMaze button pushed function
        function generateMazeButtonPushed(app)
            myMaze(app.rowsInput.Value,app.columnsInput.Value,app.mazeOutput);
            delete traversedMaze.mat
            app.solveCounter = 0;
            app.numberOfStatesTakenArray = [];
%             hold(app.stateCountDisplay,'off')
        end

        % solveMaze button pushed function
        function solveMazeButtonPushed(app)

            
            binaryMazeMaker();
            [app.numberOfStatesTaken.Value, app.numberOfPunishments.Value, moveStack] = randomMazeSolver();
            app.numberOfStatesTakenArray = [app.numberOfStatesTakenArray app.numberOfStatesTaken.Value];
            stackX = 1;
            stackY = 0;
            
            app.solveCounter = app.solveCounter + 1;
            
            while ~moveStack.empty()
                move = moveStack.pop();
                stackX = [stackX (move(1)/2)];
                stackY = [stackY (move(2)/2)];
            end

            hold(app.mazeOutput,'on')
            plot(app.mazeOutput,stackY,stackX,'m')

            if length(app.numberOfStatesTakenArray) == 1
                plot(app.stateCountDisplay, 1, app.numberOfStatesTakenArray(1), '*')
            else 
                plot(app.stateCountDisplay, 1:app.solveCounter, app.numberOfStatesTakenArray )
            end
            %             hold(app.stateCountDisplay,'on')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.mazeOutput = uiaxes(app.UIFigure);
            title(app.mazeOutput, 'Maze');
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Rows';
            app.rowsInput = uieditfield(app.UIFigure, 'numeric');
            app.rowsInput.Limits = [1 30];
            app.rowsInput.ValueDisplayFormat = '%.0f';
            app.rowsInput.Value = 1;
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Columns';
            app.columnsInput = uieditfield(app.UIFigure, 'numeric');
            app.columnsInput.Limits = [1 30];
            app.columnsInput.ValueDisplayFormat = '%.0f';
            app.columnsInput.Value = 1;
            app.solveMaze = uibutton(app.UIFigure, 'push');
            app.solveMaze.ButtonPushedFcn = createCallbackFcn(app, @solveMazeButtonPushed);
            app.solveMaze.Text = 'Solve';
            app.stateCountDisplay = uiaxes(app.UIFigure);
            title(app.stateCountDisplay, 'Average Number of States');
            xlabel(app.stateCountDisplay, 'Number of time run');
            ylabel(app.stateCountDisplay, 'Amount of states taken');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Number of States taken in the current run:';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Total number of dead ends found:';
            app.numberOfPunishments = uieditfield(app.UIFigure, 'numeric');
            app.numberOfPunishments.Editable = 'off';
            app.numberOfStatesTaken = uieditfield(app.UIFigure, 'numeric');
            app.numberOfStatesTaken.Editable = 'off';
            app.generateMaze = uibutton(app.UIFigure, 'push');
            app.generateMaze.ButtonPushedFcn = createCallbackFcn(app, @generateMazeButtonPushed);
            app.generateMaze.Text = 'Generate Maze';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MazeSolverTest()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end